classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        lbl_result           matlab.ui.control.Label
        img_result           matlab.ui.control.Image
        btn_recommend        matlab.ui.control.StateButton
        title                matlab.ui.control.Label
        SoilconditionPanel   matlab.ui.container.Panel
        img_badge            matlab.ui.control.Image
        edit_date            matlab.ui.control.EditField
        NitorigenppmLabel_6  matlab.ui.control.Label
        edit_location        matlab.ui.control.EditField
        NitorigenppmLabel_5  matlab.ui.control.Label
        edit_pH              matlab.ui.control.EditField
        NitorigenppmLabel_4  matlab.ui.control.Label
        edit_K               matlab.ui.control.EditField
        NitorigenppmLabel_3  matlab.ui.control.Label
        edit_P               matlab.ui.control.EditField
        NitorigenppmLabel_2  matlab.ui.control.Label
        edit_N               matlab.ui.control.EditField
        NitorigenppmLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_url = "https://weather.visualcrossing.com/VisualCrossingWebServices/rest/services/timeline/"
        API_KEY = '3KAJKHWT3UEMRQWF2ABKVVVZE'

        model_path = './model/trainedModel.mat';

        nitrogen = 0; % nitrogen contend [ppm]
        phosphorous = 0; % phosphorous content [ppm]
        potassium = 0; % potassium contend [ppm]
        ph = 0; % pH value
        location = ''; % location to recommend
        date = ''; % date to recommend

        address = ''; % resolved address
    end
    
    methods (Access = private)
        
        function [temp, humidity, precip, address] = get_weatherData(app, location, date)
            try
                api_url = sprintf('%s%s/%s?unitGroup=metric&key=%s&include=days&elements=datetime,temp,humidity,precip', app.base_url, location, date, app.API_KEY);                
                weatherData = webread(api_url);
                temp = weatherData.days.temp;
                humidity = weatherData.days.humidity;
                precip = weatherData.days.precip;
                address = weatherData.resolvedAddress;
            catch ME
                % Handle any errors that occur during the HTTP request
                error('Error fetching data from the API: %s', ME.message);
            end
        end
        
        function result = pred_crop(app, nitrogen, phosphorous, potassium, temperature, humidity, ph, precip)
            result = get_prediction(app, [nitrogen, phosphorous, potassium, temperature, humidity, ph, precip]);
        end
        
        function result = get_prediction(app, x)            
            % Normalize features
            meanVal = mean(x);
            stdVal = std(x);
            features = (x - meanVal) ./ stdVal;
            % Load the model
            loadedStruct = load(app.model_path);
            net = loadedStruct.net;
            result = classify(net, features);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: btn_recommend
        function btn_recommend_click(app, event)
            app.nitrogen = str2double(app.edit_N.Value);
            app.phosphorous = str2double(app.edit_P.Value);
            app.potassium = str2double(app.edit_K.Value);
            app.ph = str2double(app.edit_pH.Value);
            app.location = app.edit_location.Value;
            app.date = app.edit_date.Value;

            [temp, humidity, precip, addr] = get_weatherData(app, app.location, app.date);
            app.address =addr;

            predicted_crop = pred_crop(app, app.nitrogen, app.phosphorous, app.potassium, temp, humidity, app.ph, precip);

            result_msg = sprintf("It's better to plant %s on %s, in %s", predicted_crop, app.date, app.address);

            app.lbl_result.Text = result_msg;
            app.img_result.ImageSource = sprintf('public/crops/%s.jpg', predicted_crop);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SoilconditionPanel = uipanel(app.UIFigure);
            app.SoilconditionPanel.Title = 'Soil condition';
            app.NitorigenppmLabel = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel.Text = 'Nitorigen (ppm):';
            app.edit_N = uieditfield(app.SoilconditionPanel, 'text');
            app.NitorigenppmLabel_2 = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel_2.Text = 'Phosphorous (ppm):';
            app.edit_P = uieditfield(app.SoilconditionPanel, 'text');
            app.NitorigenppmLabel_3 = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel_3.Text = 'Potassium (ppm):';
            app.edit_K = uieditfield(app.SoilconditionPanel, 'text');
            app.NitorigenppmLabel_4 = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel_4.Text = 'pH:';
            app.edit_pH = uieditfield(app.SoilconditionPanel, 'text');
            app.edit_pH.CharacterLimits = [0 10];
            app.NitorigenppmLabel_5 = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel_5.Text = 'Location:';
            app.edit_location = uieditfield(app.SoilconditionPanel, 'text');
            app.NitorigenppmLabel_6 = uilabel(app.SoilconditionPanel);
            app.NitorigenppmLabel_6.Text = 'Date(yyyy-mm-dd):';
            app.edit_date = uieditfield(app.SoilconditionPanel, 'text');
            app.img_badge = uiimage(app.SoilconditionPanel);
            app.img_badge.ImageSource = fullfile(pathToMLAPP, 'public', 'agriIcons', 'planting.png');
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Crop Recommendation System (Weather Crossing)';
            app.btn_recommend = uibutton(app.UIFigure, 'state');
            app.btn_recommend.ValueChangedFcn = createCallbackFcn(app, @btn_recommend_click, true);
            app.btn_recommend.Text = 'Recommend';
            app.img_result = uiimage(app.UIFigure);
            app.lbl_result = uilabel(app.UIFigure);
            app.lbl_result.Text = 'No result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end